classdef ground_3GHz_rev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WriteExcelFilesCheckBox       matlab.ui.control.CheckBox
        TextArea_Multifolder          matlab.ui.control.TextArea
        GHzGroundBasedSimLabel        matlab.ui.control.Label
        ParpoolWorkersEditField       matlab.ui.control.NumericEditField
        ParpoolWorkersEditFieldLabel  matlab.ui.control.Label
        GithubLabel                   matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Button_SelectSimFolder        matlab.ui.control.Button
        ComputationSelectButtonGroup  matlab.ui.container.ButtonGroup
        SerialButton                  matlab.ui.control.ToggleButton
        ParallelButton                matlab.ui.control.ToggleButton
        Rev10Label                    matlab.ui.control.Label
    end


    methods (Access = public)


        function check_comp(app)
            if app.ParallelButton.Value==1
                app.ParpoolWorkersEditField.Visible='on';
                app.ParpoolWorkersEditFieldLabel.Visible='on';
            elseif app.SerialButton.Value==1
                app.ParpoolWorkersEditField.Visible='off';
                app.ParpoolWorkersEditFieldLabel.Visible='off';
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            check_comp(app)

            %%%%%%%%%To be safe, the number of Workers based off of 2GB rule.
            [workers,max_cores]=recommend_num_workers(app);
            app.ParpoolWorkersEditField.Limits=[1 max_cores];
            app.ParpoolWorkersEditField.Value=workers;
        end

        % Button pushed function: Button_SelectSimFolder
        function Button_SelectSimFolderPushed(app, event)

            clc;
            rev_folder=uigetdir('C:\Local Matlab Data\3.1GHz\Github_Ground');
            cd(rev_folder)
            pause(0.1);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Parallel
            if app.ParallelButton.Value==1
                parallel_flag=1; %0 --> serial, 1 --> parallel
                workers=app.ParpoolWorkersEditField.Value;
            elseif app.SerialButton.Value==1
                workers=1;
                parallel_flag=0; %This way we can see the progress bar.
            end
            workers=workers;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            tf_write_movelist_excel=app.WriteExcelFilesCheckBox.Value

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ground_3_1GHz_wrapper_rev1(app,rev_folder,parallel_flag,workers,tf_write_movelist_excel)
           
            %%%%%%%%%%Consider Adding the Email Notification if we can figure out the port error
        end

        % Selection changed function: ComputationSelectButtonGroup
        function ComputationSelectButtonGroupSelectionChanged(app, event)
            check_comp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Rev10Label = uilabel(app.UIFigure);
            app.Rev10Label.Text = 'Rev 1.0';
            app.ComputationSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ComputationSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputationSelectButtonGroupSelectionChanged, true);
            app.ComputationSelectButtonGroup.Title = 'Computation Select';
            app.ParallelButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.SerialButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.SerialButton.Text = 'Serial';
            app.Button_SelectSimFolder = uibutton(app.UIFigure, 'push');
            app.Button_SelectSimFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectSimFolderPushed, true);
            app.Button_SelectSimFolder.Text = 'START HERE: Select Sim Folder and Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.GithubLabel = uilabel(app.UIFigure);
            app.GithubLabel.Text = 'Github';
            app.ParpoolWorkersEditFieldLabel = uilabel(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel.Text = 'Workers';
            app.ParpoolWorkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParpoolWorkersEditField.Limits = [1 24];
            app.ParpoolWorkersEditField.RoundFractionalValues = 'on';
            app.ParpoolWorkersEditField.Value = 12;
            app.GHzGroundBasedSimLabel = uilabel(app.UIFigure);
            app.GHzGroundBasedSimLabel.Text = '3.1GHz Ground Based Sim';
            app.TextArea_Multifolder = uitextarea(app.UIFigure);
            app.WriteExcelFilesCheckBox = uicheckbox(app.UIFigure);
            app.WriteExcelFilesCheckBox.Text = 'Write Excel Files';
            app.WriteExcelFilesCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ground_3GHz_rev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end